classdef lyuba_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        libChoice                     matlab.ui.container.Menu
        Menu_2                        matlab.ui.container.Menu
        Menu_3                        matlab.ui.container.Menu
        Menu_4                        matlab.ui.container.Menu
        Menu_5                        matlab.ui.container.Menu
        Menu_6                        matlab.ui.container.Menu
        Menu_7                        matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        UI_TaskTable                  matlab.ui.control.Table
        UI_ConditionsTable            matlab.ui.control.Table
        AddRowToTable                 matlab.ui.control.Button
        DeleteRowFromTable            matlab.ui.control.Button
        UI_TimeBegin                  matlab.ui.control.NumericEditField
        UI_TimeEnd                    matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        UI_VectorPTable               matlab.ui.control.Table
        p0Label                       matlab.ui.control.Label
        xftxLabel                     matlab.ui.control.Label
        tLabel                        matlab.ui.control.Label
        UI_TimeOfTOpt                 matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        UI_StepSize                   matlab.ui.control.NumericEditField
        Spinner                       matlab.ui.control.Spinner
        Label_4                       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        Label_5                       matlab.ui.control.Label
        UI_MethodForExternalDropDown  matlab.ui.control.DropDown
        Label_6                       matlab.ui.control.Label
        UI_AccuracyExt                matlab.ui.control.NumericEditField
        Panel_2                       matlab.ui.container.Panel
        DropDown_2Label               matlab.ui.control.Label
        UI_MethodForInternalDropDown  matlab.ui.control.DropDown
        EditField_9Label              matlab.ui.control.Label
        UI_AccuracyInt                matlab.ui.control.NumericEditField
        Get_Solution                  matlab.ui.control.Button
        UI_Comment                    matlab.ui.control.TextArea
        Label_7                       matlab.ui.control.Label
        Tab_2                         matlab.ui.container.Tab
        TabGroup_2                    matlab.ui.container.TabGroup
        Tab_3                         matlab.ui.container.Tab
        UIAxes1                       matlab.ui.control.UIAxes
        Label_8                       matlab.ui.control.Label
        UI_XDropDown                  matlab.ui.control.DropDown
        Label_9                       matlab.ui.control.Label
        UI_YDropDown                  matlab.ui.control.DropDown
        Ui_PlotButton                 matlab.ui.control.Button
        CalculateJxButton             matlab.ui.control.Button
        xxtTab                        matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        Panel_3                       matlab.ui.container.Panel
        UI_SolutionTable              matlab.ui.control.Table
        Label_10                      matlab.ui.control.Label
        evalTime                      matlab.ui.control.EditField
        Label_11                      matlab.ui.control.Label
        JxEditFieldLabel              matlab.ui.control.Label
        JxEditField                   matlab.ui.control.EditField
        dtLabel                       matlab.ui.control.Label
        Label_12                      matlab.ui.control.Label
        Jtx1x2xnLabel                 matlab.ui.control.Label
        Jtx1x2xnEditField             matlab.ui.control.EditField
    end

    
    % Глобальные переменные
    properties (Access = private)
        
        countOfRows; % Количество строк в таблице с начальными условиями и функциями
        isStep = 0; % Нужно ли шаг записывать самим или выбирать автоматически (Ползунок у батыра, здесь он не работает но удалять не хочу, испорчу работу программы)
        
    end
    
    % Пространство функций
    methods (Access = private)
        
        % Эта функция загружает пример
        
        function [] = set_values(app, filepath)
            
            load(filepath, 'saved');
            
            app.UI_AccuracyExt.Value = saved.accuracyExt;
            app.UI_AccuracyInt.Value = saved.accuracyInt;
            
            app.UI_ConditionsTable.Data = saved.conditionsTableData;
            app.UI_VectorPTable.Data = saved.vectorPTableData;
            app.UI_TaskTable.Data = saved.taskTableData;
            
            app.UI_TimeBegin.Value = saved.timeBegin;
            app.UI_TimeEnd.Value = saved.timeEnd;
            app.UI_TimeOfTOpt.Value = saved.timeOfTOpt;
            
            app.UI_MethodForExternalDropDown.Value = saved.methodForExternal;
            app.UI_MethodForInternalDropDown.Value = saved.methodForInternal;
            
            %app.UI_StepsCount.Value = saved.stepsCount;
            app.Spinner.Value = saved.stepsCount;
            app.UI_StepSize.Value = saved.stepSize;
            
            app.countOfRows = saved.countOfRows;            
            app.UI_Comment.Value = saved.comment;
            
            
            items = strings(1, app.countOfRows);
            for i = 1:app.countOfRows
                items(i) = strcat('x', num2str(i));
            end
            app.UI_XDropDown.Items = ['t', items];
            app.UI_YDropDown.Items = items;
            if app.countOfRows > 1
                    app.DeleteRowFromTable.Enable = 'on';
            end
            
            if size(app.UI_SolutionTable.Data, 1)
                app.Ui_PlotButton.Enable = 'on';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Данная функция запускается сразу же при запуске программы
            clc % очищаем консоль в главном окне матлаба (дебаггинг)
            app.countOfRows = 1;% При запуске у нас только один столбец изначально
            
            newRow = strcat('x', num2str(app.countOfRows), '''');%Эта переменная хранит в себе x'i, где i - число строк 
            app.UI_TaskTable.Data = {newRow, ''};% Запишем в столбец с x'i это значение (чтобы знать какую строку уравнения сейчас записываем)
            
            app.UI_ConditionsTable.Data = {''};% Запишем пустоту в столбец с f(t,x)
            
            app.UI_VectorPTable.Data = 0;% запишем 0 в столбец p0 (это начальное условие )
            
            
            app.UIAxes1.XLabel.String = app.UI_XDropDown.Value;% Горизонтальная ось. Записываем значение из ползунка в график
            app.UIAxes1.YLabel.String = app.UI_YDropDown.Value;% Вертикальная ось. Записываем значение из ползунка в график
            
            % здесь получается разные настройки чтобы график отображался
            % удобнее.
            pan(app.UIAxes1,'on');          % or 'off'
            zoom(app.UIAxes1,'on');         % or 'off'
            app.UIAxes1.XLimMode = 'auto';
            app.UIAxes1.YLimMode = 'auto';
            app.UIAxes1.ZLimMode = 'auto';

            
            app.DeleteRowFromTable.Enable = 'off';% мы выключаем возможность нажать на красную кнопку, так как удалять нечего (всего лишь один столбец есть)
            app.Ui_PlotButton.Enable = 'off';% Так как нет данных то и показывать на графике нечего
           
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);% мы закрываем программу при нажатии кнопки выхода
        end

        % Button pushed function: AddRowToTable
        function AddRowToTableButtonPushed(app, event)
            app.countOfRows = app.countOfRows + 1;% увеличиваем число столбцов на единицу
            
            newRow = {strcat('x', num2str(app.countOfRows), ''''), ''};% создаем x'i
            app.UI_TaskTable.Data = [app.UI_TaskTable.Data; newRow];% записываем в нашу таблицу 
            
            newRow = {''};% формулу вводит пользователь, так что мы оставляем поле f(t,x) пустым
            app.UI_ConditionsTable.Data = [app.UI_ConditionsTable.Data; newRow];
            
            newElem = 0;% значение вводит пользователь, так что мы оставляем вектор p0  пустым
            app.UI_VectorPTable.Data = [app.UI_VectorPTable.Data, newElem];
            
            newElem = strcat('x', num2str(app.countOfRows));%записываем новую xi в ползунок горизонтальной оси
            app.UI_XDropDown.Items = [app.UI_XDropDown.Items, newElem];
            
            newElem = strcat('x', num2str(app.countOfRows));%записываем новую xi в ползунок вертикальной оси
            app.UI_YDropDown.Items = [app.UI_YDropDown.Items, newElem];
            
            % Если число строк больше одного, то можно снять запрет с
            % удаления строк.
            if app.countOfRows > 1
                    app.DeleteRowFromTable.Enable = 'on';
            end
        end

        % Button pushed function: DeleteRowFromTable
        function DeleteRowFromTableButtonPushed(app, event)
            %Если строк больше 1-го, то можно удалять
            if app.countOfRows > 1
                
                % но если их число меньше трех то эта опция отключается
                if app.countOfRows < 3
                    app.DeleteRowFromTable.Enable = 'off';
                end
                
                % Удаляем колонную x'i
                app.UI_TaskTable.Data(app.countOfRows, :) = [];
                
                % удаляем колонну f(t,x)
                app.UI_ConditionsTable.Data(app.countOfRows, :) = [];
                
                % удаляем p0
                app.UI_VectorPTable.Data(:, app.countOfRows) = [];
                
                % удаляем xi из ползунков горизонтальной и вертикальной оси
                if strcmp(app.UI_XDropDown.Items(app.countOfRows), app.UI_XDropDown.Value)
                    app.UI_XDropDown.Value = app.UI_XDropDown.Items(app.countOfRows);
                end
                if strcmp(app.UI_YDropDown.Items(app.countOfRows), app.UI_YDropDown.Value)
                    app.UI_YDropDown.Value = app.UI_YDropDown.Items(app.countOfRows - 1);
                end
                app.UIAxes1.XLabel.String = app.UI_XDropDown.Value;
                app.UIAxes1.YLabel.String = app.UI_YDropDown.Value;
                
                app.UI_XDropDown.Items(:, app.countOfRows + 1) = [];
                app.UI_YDropDown.Items(:, app.countOfRows) = [];
                
                % в конечном итоге снижаем количество на единицу
                app.countOfRows = app.countOfRows - 1;
            end
        end

        % Button pushed function: Ui_PlotButton
        function Ui_PlotButtonPushed(app, event)
            
            % Берем решение (t,x) из таблицы решений
            tableData = app.UI_SolutionTable.Data;
            
            % в str_x лежат названия: t,x1,x2,...,xn
            % в x лежит то что мы выбрали в ползунке горизонтальной оси
            str_x = app.UI_XDropDown.Value;
            if strcmp(str_x, 't')
                x = 1;
            else
                x = str2double(str_x(2:end)) + 1;
            end
            
            % в str_y лежат названия: t,x1,x2,...,xn
            % в y лежит то что мы выбрали в ползунке вертикальной оси
            str_y = app.UI_YDropDown.Value;
            y = str2double(str_y(2:end)) + 1;
            
            
            % в xData и yData лежат выбранные нами вектора
            xData = tableData(:, x);
            yData = tableData(:, y);
            
            %Строим эти графики (Фазовый Портрет)
            plot(app.UIAxes1, xData, yData);
            legend(app.UIAxes1, strcat(str_x, " ", str_y));
        end

        % Value changed function: UI_XDropDown
        function UI_XDropDownValueChanged(app, event)
            % Если мы меняем значение ползунка горизонтальной оси, то
            % засовываем в график фазового портрета
            app.UIAxes1.XLabel.String = app.UI_XDropDown.Value;
            
        end

        % Value changed function: UI_YDropDown
        function UI_YDropDownValueChanged(app, event)
            % Если мы меняем значение ползунка вертикальной оси, то
            % засовываем в график фазового портрета
            app.UIAxes1.YLabel.String = app.UI_YDropDown.Value;
            
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            % Загружаем в filename - название файла. pathname -
            % расположение файла.
            % Кроме того наши опции настроены чтобы мы файл открывали 
            [filename, pathname] = uigetfile('*.mat', 'Открыть файл');
            if pathname == 0
                return
            end
            % Обьединяем чтобы получитьполное название
            loadDataName = fullfile(pathname, filename);
            
            % из файла loadDataName мы загрузим контейнер saved. Этот
            % контейнер описан ниже:
            load(loadDataName, 'saved');
            
            % Загружаем точность вычисления внешней и внутренней задачи
            app.UI_AccuracyExt.Value = saved.accuracyExt;
            app.UI_AccuracyInt.Value = saved.accuracyInt;
            
            % Таблица с начальными значениями
            app.UI_ConditionsTable.Data = saved.conditionsTableData;
            app.UI_VectorPTable.Data = saved.vectorPTableData;
            app.UI_TaskTable.Data = saved.taskTableData;
            
            % Начало конец и t*
            app.UI_TimeBegin.Value = saved.timeBegin;
            app.UI_TimeEnd.Value = saved.timeEnd;
            app.UI_TimeOfTOpt.Value = saved.timeOfTOpt;
            
            % загружаем используемый ode для внутренней или внешней задачи
            app.UI_MethodForExternalDropDown.Value = saved.methodForExternal;
            app.UI_MethodForInternalDropDown.Value = saved.methodForInternal;
            
            % Размер шага сетки
            app.UI_StepSize.Value = saved.stepSize;
            
            % Количество строк и содержание коментария к задаче
            app.countOfRows = saved.countOfRows;            
            app.UI_Comment.Value = saved.comment;
            
            
            items = strings(1, app.countOfRows);
            for i = 1:app.countOfRows
                items(i) = strcat('x', num2str(i));
            end
            app.UI_XDropDown.Items = ['t', items];
            app.UI_YDropDown.Items = items;
            if app.countOfRows > 1
                    app.DeleteRowFromTable.Enable = 'on';
            end
            
            if size(app.UI_SolutionTable.Data, 1)
                app.Ui_PlotButton.Enable = 'on';
            end
           
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % Сохраняем в filename - название файла. pathname -
            % расположение файла.
            % Кроме того наши опции настроены чтобы мы файл сохраняли
            [filename, pathname] = uiputfile('Task.mat', 'Сохранить как...');
            saveDataName = fullfile(pathname, filename);
            if pathname == 0
                return
            end
            
            % Сохраняем точность вычисления внешней и внутренней задачи
            saved.accuracyExt = app.UI_AccuracyExt.Value;
            saved.accuracyInt = app.UI_AccuracyInt.Value;
            
            % Таблица с начальными значениями
            saved.conditionsTableData = app.UI_ConditionsTable.Data;
            saved.vectorPTableData = app.UI_VectorPTable.Data;
            saved.taskTableData = app.UI_TaskTable.Data;
            
            % Начало конец и t*
            saved.timeBegin = app.UI_TimeBegin.Value;
            saved.timeEnd = app.UI_TimeEnd.Value;
            saved.timeOfTOpt = app.UI_TimeOfTOpt.Value;
            
            % загружаем используемый ode для внутренней или внешней задачи
            saved.methodForExternal = app.UI_MethodForExternalDropDown.Value;
            saved.methodForInternal = app.UI_MethodForInternalDropDown.Value;
            
            % Размер шага сетки
            saved.stepSize = app.UI_StepSize.Value;
            
            % Количество строк и содержание коментария к задаче
            saved.countOfRows = app.countOfRows;
            saved.comment = app.UI_Comment.Value;
            
            %из файла saveDataName мы сохранили контейнер saved. Этот
            save(saveDataName,'saved');
        end

        % Button pushed function: Get_Solution
        function Get_SolutionPushed(app, event)

                app.Ui_PlotButton.Enable = 'off';
                
                % Начинаем расчет
                tStart = tic;
                solution = solve_problem(app.UI_TaskTable.Data, app.UI_ConditionsTable.Data, app.UI_TimeBegin.Value, app.UI_TimeEnd.Value, app.Spinner.Value, app.UI_AccuracyExt.Value, app.UI_AccuracyInt.Value, str2double(app.UI_MethodForExternalDropDown.Value), app.UI_StepSize.Value, str2double(app.UI_MethodForInternalDropDown.Value), app.UI_TimeOfTOpt.Value, app.UI_VectorPTable.Data, app.isStep);
                tEnd = toc(tStart);
                % Записываем время выполнения
                app.evalTime.Value = string(tEnd);
                
                % newCol = [0, y1,y2,...,yn]
                newCols = strings(1, app.countOfRows + 1);
                for i = 2:app.countOfRows + 1
                    newCols(i) = strcat('x', num2str(i - 1));
                end
                
                % newCol = [t, y1,y2,...,yn]
                newCols(1) = 't';
                % Записывание название колонки решений и значения из
                % решения
                app.UI_SolutionTable.ColumnName = newCols;
                app.UI_SolutionTable.Data = solution;
                
                % Строим все функции x = x(t). hold on hold off нужны чтобы
                % записать все функции. Без них выведен будет только
                % последний график. Это своего рода режим записи
                plot(app.UIAxes2, 0, 0);
                hold(app.UIAxes2,'on');
                    tableData = app.UI_SolutionTable.Data;
                    tData = tableData(:, 1);
                    legstr = [];
                    for i = 2:app.countOfRows + 1
                        xData = tableData(:, i);
                        legstr = [legstr; strcat('x', num2str(i - 1))];
                        plot(app.UIAxes2, tData, xData);
                    end
                hold(app.UIAxes2,'off');
                % легенда (x1,x2,..,xn)
                legend(app.UIAxes2, legstr);
                app.Ui_PlotButton.Enable = 'on';

        end

        % Callback function
        function PlotButton_2Pushed(app, event)
            
            % Строим все функции x = x(t). hold on hold off нужны чтобы
            % записать все функции. Без них выведен будет только
            % последний график. Это своего рода режим записи
            plot(app.UIAxes2, 0, 0);
            hold(app.UIAxes2,'on');
                tableData = app.UI_SolutionTable.Data;
                tData = tableData(:, 1);
                legstr = [];
                for i = 2:app.countOfRows + 1
                    xData = tableData(:, i);
                    legstr = [legstr; strcat('x', num2str(i - 1))];
                    plot(app.UIAxes2, tData, xData);
                end
            hold(app.UIAxes2,'off');
            legend(app.UIAxes2, legstr);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Нажатие кнопок. Тут думаю все понятно. В зависимости от
            % нажатой кнопки выполняется сверху
            key = event.Key;
            switch key
                case 'q'
                    app.ExitSelected(app);
                case 's'
                    app.SaveSelected(app);
                case 'l'
                    app.LoadSelected(app);
                case 'a'
                    app.AboutSelected(app);
                case 'h'
                    app.HelpSelected(app);
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;% об авторе
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            help_window; %помощь
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            set_values(app, 'Examples/Example1.mat');
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            set_values(app, 'Examples/Example2.mat');
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            set_values(app, 'Examples/Example3.mat');
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            set_values(app, 'Examples/Example4.mat');
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            set_values(app, 'Examples/Example5.mat');
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            set_values(app, 'Examples/Example6.mat');
        end

        % Button pushed function: CalculateJxButton
        function CalculateJxButtonPushed(app, event)
            % Берем решение (t,x) из таблицы решений
            tableData = app.UI_SolutionTable.Data;
            
            n = size(tableData,2) - 1;%Количество уравнений
            tt = tableData(:,1);
            xx = tableData(:,2:(n+1));
            
            syms t
            syms x [1 n]
            Jx_sym = str2sym( app.JxEditField.Value);
            Jx_func = matlabFunction(Jx_sym,'Vars',[t x]);
            
            
            input_arg = cell(1,n+1);
            Jx = zeros(size(tt,1),1);
            for i = 1:size(tt,1)
                %Jx(i) = Jx_func(tt(i),xx(i,1),..,xx(i,n));
                input_arg{1} = tt(i);
                for j = 1:n
                    input_arg{j+1} = xx(i,j);
                end
                Jx(i) = Jx_func(input_arg{:});
                
            end
            
            app.Jtx1x2xnEditField.Value = num2str(trapz(tt(:,1),Jx(:,1),1));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Решение краевой задачи методом продолжения по параметру';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'Файл';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Помощь';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'О программе';
            app.libChoice = uimenu(app.UIFigure);
            app.libChoice.Text = 'Библиотека примеров';
            app.Menu_2 = uimenu(app.libChoice);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = 'Пример 1';
            app.Menu_3 = uimenu(app.libChoice);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = 'Пример 2';
            app.Menu_4 = uimenu(app.libChoice);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = 'Пример 3';
            app.Menu_5 = uimenu(app.libChoice);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = 'Пример 4';
            app.Menu_6 = uimenu(app.libChoice);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = 'Пример 5';
            app.Menu_7 = uimenu(app.libChoice);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = 'Пример 6';
            app.ExitMenu = uimenu(app.UIFigure);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Выход';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Вводные данные';
            app.UI_TaskTable = uitable(app.Tab);
            app.UI_TaskTable.ColumnName = {'x'''; 'f(t,x) система'};
            app.UI_TaskTable.RowName = {};
            app.UI_TaskTable.ColumnEditable = [false true];
            app.UI_ConditionsTable = uitable(app.Tab);
            app.UI_ConditionsTable.ColumnName = {'Краевые условия'};
            app.UI_ConditionsTable.RowName = {};
            app.UI_ConditionsTable.ColumnEditable = true;
            app.AddRowToTable = uibutton(app.Tab, 'push');
            app.AddRowToTable.ButtonPushedFcn = createCallbackFcn(app, @AddRowToTableButtonPushed, true);
            app.AddRowToTable.Text = 'Добавить уравнение';
            app.DeleteRowFromTable = uibutton(app.Tab, 'push');
            app.DeleteRowFromTable.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowFromTableButtonPushed, true);
            app.DeleteRowFromTable.Text = 'Удалить уравнение';
            app.UI_TimeBegin = uieditfield(app.Tab, 'numeric');
            app.UI_TimeEnd = uieditfield(app.Tab, 'numeric');
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Начальная Граница:';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = 'Конечная Граница:';
            app.UI_VectorPTable = uitable(app.Tab);
            app.UI_VectorPTable.ColumnName = '';
            app.UI_VectorPTable.RowName = {};
            app.UI_VectorPTable.ColumnEditable = true;
            app.p0Label = uilabel(app.Tab);
            app.p0Label.Text = 'Вектор начальных условий p0:';
            app.xftxLabel = uilabel(app.Tab);
            app.xftxLabel.Text = 'Ввод Начальных Условий и уравнения типа x'' = f(t, x) ';
            app.tLabel = uilabel(app.Tab);
            app.tLabel.Text = 'Момент времени t*:';
            app.UI_TimeOfTOpt = uieditfield(app.Tab, 'numeric');
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = 'Шаг сетки';
            app.UI_StepSize = uieditfield(app.Tab, 'numeric');
            app.UI_StepSize.Value = 0.01;
            app.Spinner = uispinner(app.Tab);
            app.Spinner.Limits = [1 10];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.Value = 2;
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = 'Количество Итераций';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Внутреняя задача';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = 'Метод решения';
            app.UI_MethodForExternalDropDown = uidropdown(app.Panel);
            app.UI_MethodForExternalDropDown.Items = {'ode45', 'ode23', 'ode23t', 'ode113', 'ode23s', 'ode15s'};
            app.UI_MethodForExternalDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.UI_MethodForExternalDropDown.Value = '1';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = 'Точность';
            app.UI_AccuracyExt = uieditfield(app.Panel, 'numeric');
            app.UI_AccuracyExt.Value = 0.0001;
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Внешняя задача';
            app.DropDown_2Label = uilabel(app.Panel_2);
            app.DropDown_2Label.Text = 'Метод решения';
            app.UI_MethodForInternalDropDown = uidropdown(app.Panel_2);
            app.UI_MethodForInternalDropDown.Items = {'ode45', 'ode23', 'ode23t', 'ode113', 'ode23s', 'ode15s'};
            app.UI_MethodForInternalDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.UI_MethodForInternalDropDown.Value = '1';
            app.EditField_9Label = uilabel(app.Panel_2);
            app.EditField_9Label.Text = 'Точность';
            app.UI_AccuracyInt = uieditfield(app.Panel_2, 'numeric');
            app.UI_AccuracyInt.Value = 0.0001;
            app.Get_Solution = uibutton(app.Tab, 'push');
            app.Get_Solution.ButtonPushedFcn = createCallbackFcn(app, @Get_SolutionPushed, true);
            app.Get_Solution.Text = 'Решить';
            app.UI_Comment = uitextarea(app.Tab);
            app.Label_7 = uilabel(app.Tab);
            app.Label_7.Text = 'Комментарии к задаче:';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Решение';
            app.TabGroup_2 = uitabgroup(app.Tab_2);
            app.Tab_3 = uitab(app.TabGroup_2);
            app.Tab_3.Title = 'Фазовый портрет';
            app.UIAxes1 = uiaxes(app.Tab_3);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.Label_8 = uilabel(app.Tab_3);
            app.Label_8.Text = 'Горизонтальная ось';
            app.UI_XDropDown = uidropdown(app.Tab_3);
            app.UI_XDropDown.Items = {'t', 'x1'};
            app.UI_XDropDown.ValueChangedFcn = createCallbackFcn(app, @UI_XDropDownValueChanged, true);
            app.UI_XDropDown.Value = 't';
            app.Label_9 = uilabel(app.Tab_3);
            app.Label_9.Text = 'Вертикальная ось';
            app.UI_YDropDown = uidropdown(app.Tab_3);
            app.UI_YDropDown.Items = {'x1'};
            app.UI_YDropDown.ValueChangedFcn = createCallbackFcn(app, @UI_YDropDownValueChanged, true);
            app.UI_YDropDown.Value = 'x1';
            app.Ui_PlotButton = uibutton(app.Tab_3, 'push');
            app.Ui_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Ui_PlotButtonPushed, true);
            app.Ui_PlotButton.Text = 'Обновить график';
            app.CalculateJxButton = uibutton(app.Tab_3, 'push');
            app.CalculateJxButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateJxButtonPushed, true);
            app.CalculateJxButton.Text = 'Вычислить функционал';
            app.xxtTab = uitab(app.TabGroup_2);
            app.xxtTab.Title = 'Все графики x = x(t)';
            app.UIAxes2 = uiaxes(app.xxtTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'X')
            app.Panel_3 = uipanel(app.Tab_2);
            app.Panel_3.Title = 'Результаты в виде таблицы: ';
            app.UI_SolutionTable = uitable(app.Panel_3);
            app.UI_SolutionTable.ColumnName = '';
            app.UI_SolutionTable.RowName = {};
            app.Label_10 = uilabel(app.Tab_2);
            app.Label_10.Text = 'Время выполнения программы:';
            app.evalTime = uieditfield(app.Tab_2, 'text');
            app.evalTime.Value = '0';
            app.Label_11 = uilabel(app.Tab_2);
            app.Label_11.Text = 'Функционал:';
            app.JxEditFieldLabel = uilabel(app.Tab_2);
            app.JxEditFieldLabel.Text = 'J(x) = ';
            app.JxEditField = uieditfield(app.Tab_2, 'text');
            app.JxEditField.Value = 'x1*x2+t';
            app.dtLabel = uilabel(app.Tab_2);
            app.dtLabel.Text = 'dt';
            app.Label_12 = uilabel(app.Tab_2);
            app.Label_12.Text = 'Интегрирование идет от начальной к конечной границе';
            app.Jtx1x2xnLabel = uilabel(app.Tab_2);
            app.Jtx1x2xnLabel.Text = 'J(t,x1,x2,...,xn) = ';
            app.Jtx1x2xnEditField = uieditfield(app.Tab_2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lyuba_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end